classdef nethealth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetHealthUIFigure     matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        HostEditFieldLabel    matlab.ui.control.Label
        HostEditField         matlab.ui.control.EditField
        CountLabel            matlab.ui.control.Label
        CountText             matlab.ui.control.Label
        LostLabel             matlab.ui.control.Label
        LostText              matlab.ui.control.Label
        RateHzEditFieldLabel  matlab.ui.control.Label
        RateHzEditField       matlab.ui.control.NumericEditField
        RunSwitchLabel        matlab.ui.control.Label
        RunSwitch             matlab.ui.control.RockerSwitch
        DelayLabel            matlab.ui.control.Label
        DelayText             matlab.ui.control.Label
        HealthLamp            matlab.ui.control.Lamp
        Gauge                 matlab.ui.control.SemicircularGauge
        ClearButton           matlab.ui.control.Button
        SinglePingButton      matlab.ui.control.Button
        OnLamp                matlab.ui.control.Lamp
        RightPanel            matlab.ui.container.Panel
        DelayAxes             matlab.ui.control.UIAxes
        HistAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pingTimer timer % timer for ping operations
        pingCount = 0 % number of pings
        pingSuccess = 0 % number of successes
        pingDelays = []; % history of delays
        dispTimer timer % Description
        histTimer timer % Description
        onColor = [0.1 0.8 0.1] % Description
        offColor = [0 0.2 0] % Description
    end
    
    methods (Access = private)
        
        function handlePing(app, ~, ~)
            hoststr = app.HostEditField.Value;
            [succ, delay] = ping(hoststr);
            
            app.pingDelays(end + 1) = delay;
            app.pingCount = app.pingCount + 1;
            if succ
                app.pingSuccess = app.pingSuccess + 1;
                app.DelayText.Text = num2str(delay, 4);
                app.Gauge.Value = delay;
            else
                app.DelayText.Text = 'LOST';
                app.Gauge.Value = 100;
            end
            app.CountText.Text = num2str(app.pingCount);
            app.LostText.Text = num2str(100*(1 - app.pingSuccess/app.pingCount), 3);
            
            newColor = [0 0.5 0];
            if delay > 100
                if delay > 200
                    newColor = [0.75 0 0];
                else
                    newColor = [1 0.5 0];
                end
            elseif isnan(delay)
                newColor = [1 0 0];
            end
            app.DelayText.FontColor = newColor;
            app.HealthLamp.Color = newColor; 
        end
        
        function resetDisplay(app)
            app.DelayText.Text = '--';
            app.DelayText.FontColor = [0 0 0];
            app.CountText.Text = '0';
            app.LostText.Text = '--';
            app.HealthLamp.Color = [0.5 0.5 0.5];
            app.Gauge.Value = 0;
            cla(app.DelayAxes)
            cla(app.HistAxes)
        end
        
        function updateDisp(app, ~, ~)
            if app.pingCount > 1
                nd = min(app.pingCount, 256);
                plotDelays = app.pingDelays(end-nd+1:end);
                l = plot(app.DelayAxes, 1:nd, plotDelays);
                l.LineWidth = 3;
                axis(app.DelayAxes, 'tight')
                app.DelayAxes.YLim = [0 (max(plotDelays) + 10)];
            end
        end
        
        function updateHist(app, ~, ~)
            if app.pingCount > 1
                nh = min(app.pingCount, 2048);
                histdelays = sort(app.pingDelays(1:nh));
                histogram(app.HistAxes, histdelays(1:ceil(0.9*nh)));
                histperiod = min(60, ceil(app.pingCount/app.RateHzEditField.Value/20));
                if app.histTimer.Period ~= histperiod
                    stop(app.histTimer);
                    app.histTimer.Period = histperiod;
                    start(app.histTimer);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.resetDisplay();
            app.pingTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Default period is 1 second
                'BusyMode', 'drop',...               % drop callbacks when busy
                'TimerFcn', @app.handlePing);        % callback function
            app.dispTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Default period is 1 second
                'BusyMode', 'drop',...               % drop callbacks when busy
                'TimerFcn', @app.updateDisp);        % callback function
            app.histTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Default period is 10 seconds
                'BusyMode', 'drop',...               % drop callbacks when busy
                'TimerFcn', @app.updateHist);        % callback function
            hoststr = getenv('PING_HOST');
            if ~isempty(hoststr)
                app.HostEditField.Value = hoststr;
            end
            app.OnLamp.Color = app.offColor;
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            value = app.RunSwitch.Value;
            if strcmp(value,'On')  %% turning ON timer
                app.SinglePingButton.Enable = false;
                if strcmp(app.pingTimer.Running, 'off')
                    app.pingTimer.Period = 1/app.RateHzEditField.Value;
                    start(app.pingTimer);
                end
                if strcmp(app.dispTimer.Running, 'off')
                    start(app.dispTimer);
                end
                if strcmp(app.histTimer.Running, 'off')
                    start(app.histTimer);
                end
                app.OnLamp.Color = app.onColor;
            else  %% turning OFF timer
                app.SinglePingButton.Enable = true;
                stop(app.pingTimer);
                stop(app.dispTimer);
                stop(app.histTimer);
                app.OnLamp.Color = app.offColor;
            end
        end

        % Value changed function: RateHzEditField
        function RateHzEditFieldValueChanged(app, event)
            value = app.RateHzEditField.Value;
            if value <= 10
                newRate = value;
                runagain = strcmp(app.pingTimer.Running, 'on');
                stop(app.pingTimer);
                app.pingTimer.Period = 1/newRate;
                if runagain
                    start(app.pingTimer);
                end
            end
        end

        % Close request function: NetHealthUIFigure
        function NetHealthUIFigureCloseRequest(app, event)
            stop(app.pingTimer)
            delete(app.pingTimer)
            delete(app.histTimer)
            delete(app.dispTimer)
            delete(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.pingCount = 0;
            app.pingSuccess = 0;
            app.pingDelays = [];
            if strcmp(app.pingTimer.Running, 'off')
                app.resetDisplay();
            end
        end

        % Button pushed function: SinglePingButton
        function SinglePingButtonPushed(app, event)
            app.handlePing();
            app.updateDisp();
            app.updateHist();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NetHealthUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetHealthUIFigure = uifigure('Visible', 'off');
            app.NetHealthUIFigure.Name = 'NetHealth';
            app.NetHealthUIFigure.CloseRequestFcn = createCallbackFcn(app, @NetHealthUIFigureCloseRequest, true);
            app.NetHealthUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.NetHealthUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HostEditFieldLabel = uilabel(app.LeftPanel);
            app.HostEditFieldLabel.Text = 'Host';
            app.HostEditField = uieditfield(app.LeftPanel, 'text');
            app.HostEditField.Value = 'aws.amazon.com';
            app.CountLabel = uilabel(app.LeftPanel);
            app.CountLabel.Text = 'Count:';
            app.CountText = uilabel(app.LeftPanel);
            app.CountText.Text = '0';
            app.LostLabel = uilabel(app.LeftPanel);
            app.LostLabel.Text = 'Lost (%):';
            app.LostText = uilabel(app.LeftPanel);
            app.LostText.Text = '--';
            app.RateHzEditFieldLabel = uilabel(app.LeftPanel);
            app.RateHzEditFieldLabel.Text = 'Rate (Hz)';
            app.RateHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RateHzEditField.ValueChangedFcn = createCallbackFcn(app, @RateHzEditFieldValueChanged, true);
            app.RateHzEditField.Value = 1;
            app.RunSwitchLabel = uilabel(app.LeftPanel);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.RunSwitch.Tooltip = {'Start ping timer'};
            app.DelayLabel = uilabel(app.LeftPanel);
            app.DelayLabel.Text = 'Delay (ms):';
            app.DelayText = uilabel(app.LeftPanel);
            app.DelayText.Text = '--';
            app.HealthLamp = uilamp(app.LeftPanel);
            app.Gauge = uigauge(app.LeftPanel, 'semicircular');
            app.Gauge.Limits = [0 500];
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Reset history'};
            app.ClearButton.Text = 'Clear';
            app.SinglePingButton = uibutton(app.LeftPanel, 'push');
            app.SinglePingButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePingButtonPushed, true);
            app.SinglePingButton.Tooltip = {'Single ping. Single ping, only!'};
            app.SinglePingButton.Text = 'Single Ping';
            app.OnLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.DelayAxes = uiaxes(app.RightPanel);
            title(app.DelayAxes, 'Delay History')
            xlabel(app.DelayAxes, 'Sample')
            ylabel(app.DelayAxes, 'Delay (ms)')
            app.HistAxes = uiaxes(app.RightPanel);
            title(app.HistAxes, 'Delay Statistics')
            xlabel(app.HistAxes, 'Delay (ms)')
            ylabel(app.HistAxes, 'N')
            % Show the figure after all components are created
            app.NetHealthUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nethealth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetHealthUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetHealthUIFigure)
        end
    end
end